classdef SetTestNameComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestDirectoryPanel              matlab.ui.container.Panel
        NameTestPanel                   matlab.ui.container.Panel
        StandardNamingPanel             matlab.ui.container.Panel
        LengthcmDropDown                matlab.ui.control.DropDown
        LengthcmDropDownLabel           matlab.ui.control.Label
        SubmitButton_2                  matlab.ui.control.Button
        ModelYearEditField              matlab.ui.control.EditField
        ModelYearEditFieldLabel         matlab.ui.control.Label
        ModelNameEditField              matlab.ui.control.EditField
        ModelNameEditFieldLabel         matlab.ui.control.Label
        ManufacturerEditField           matlab.ui.control.EditField
        ManufacturerEditFieldLabel      matlab.ui.control.Label
        CustomNamePanel                 matlab.ui.container.Panel
        SubmitButton                    matlab.ui.control.Button
        NameEditField                   matlab.ui.control.EditField
        NameEditFieldLabel              matlab.ui.control.Label
        CustomNameSwitch                matlab.ui.control.Switch
        CustomNameSwitchLabel           matlab.ui.control.Label
        SelectExistingTestNamePanel     matlab.ui.container.Panel
        SubmitButton_3                  matlab.ui.control.Button
        ExistingTestNamesDropDown       matlab.ui.control.DropDown
        ExistingTestNamesDropDownLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitchLabel  matlab.ui.control.Label
        HasThisSkiAlreadyBeenTestedSwitch  matlab.ui.control.Switch
    end

    
    methods (Access = public)
        
        function cleaned_dirs = getExsitingTestDirNames(comp)
            current_folder = pwd;
            split_name = split(current_folder,"\Ski_Flex\");
            data_folder_path = strcat(split_name(1),"\Ski_Flex\0_Data");
            l = dir(data_folder_path);
            dirs = {l.name};
            cleaned_dirs = dirs(1,3:end);
            cleaned_dirs(ismember(cleaned_dirs,'ReadMe.txt')) = {''};
            % use contians to fins csv, png, and txt files to remove if
            % neccessary
            cleaned_dirs = cleaned_dirs(~cellfun(@isempty, cleaned_dirs));
        end

        function result = checkTestPlaceholderNameError(comp, message, placeholder, input)
            disp("placeholder: ");
            disp(placeholder);
            disp("input: ");
            disp(input);
            if input == placeholder
               fig = uifigure;
               uialert(fig,message,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end

        function result = checkIfDirNameExists(comp, dir_name)
            existing_dir_names = getExsitingTestDirNames(comp);
            if ismember(dir_name, existing_dir_names)
               str = strcat("Ski Test Name Already Exists. Please input new name or select existing name. \n Input: \n ", dir_name);
               fig = uifigure;
               uialert(fig,str,'Ski Test Name Error');
               result = false;
               return;
            end
            result = true;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: HasThisSkiAlreadyBeenTestedSwitch
        function HasThisSkiAlreadyBeenTestedSwitchValueChanged(comp, event)
            value = comp.HasThisSkiAlreadyBeenTestedSwitch.Value;
            if value == "Yes"      
                comp.SelectExistingTestNamePanel.Enable = "on";
                comp.NameTestPanel.Enable = "off";
            else
                comp.SelectExistingTestNamePanel.Enable = "off";
                comp.NameTestPanel.Enable = "on";
            end
        end

        % Drop down opening function: ExistingTestNamesDropDown
        function ExistingTestNamesDropDownOpening(comp, event)
            comp.ExistingTestNamesDropDown.Items = getExsitingTestDirNames(comp);
        end

        % Button pushed function: SubmitButton_3
        function SubmitButton_3Pushed(comp, event)
            % global test_directory_name;
            selectedSki = comp.ExistingTestNamesDropDown.Value;
            str = strcat("Ski not selected. \n Selected: \n ", selectedSki);
            res = checkTestPlaceholderNameError(comp, str, convertCharsToStrings(comp.ExistingTestNamesDropDown.Placeholder), convertCharsToStrings(selectedSki));
            if ~res; return; end;
            test_directory_name = selectedSki;   
            assignin('base','dir_name',test_directory_name);
            disp(selectedSki);
        end

        % Button down function: TestDirectoryPanel
        function TestDirectoryPanelButtonDown(comp, event)
            comp.SelectExistingTestNamePanel.Enable = "off";
            comp.NameTestPanel.Enable = "off";
        end

        % Button down function: NameTestPanel
        function NameTestPanelButtonDown(comp, event)
            comp.CustomNamePanel.Enable = "off";
            comp.StandardNamingPanel = "off";
        end

        % Value changed function: CustomNameSwitch
        function CustomNameSwitchValueChanged(comp, event)
            value = comp.CustomNameSwitch.Value;
            if value == "Yes"
               comp.CustomNamePanel.Enable = "on";
               comp.StandardNamingPanel.Enable = "off";
            else
               comp.CustomNamePanel.Enable = "off";
               comp.StandardNamingPanel.Enable = "on";
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(comp, event)
            %global test_directory_name;
            inputtedName = comp.NameEditField.Value;           
            checkIfDirNameExists(comp, inputtedName);
            res = checkTestPlaceholderNameError(comp, "manufacturer name not inputted", convertCharsToStrings(comp.NameEditField.Placeholder), inputtedName);
            if ~res; return; end;
            test_directory_name = inputtedName;
            assignin('base','dir_name',test_directory_name);
            disp(inputtedName);           
        end

        % Drop down opening function: LengthcmDropDown
        function LengthcmDropDownOpening(comp, event)
            %cm_arr = regexp(cellstr(sprintf('%d ',1:200)),' ','split');
            cm_arr = sprintfc('%d',100:200); % beware, undocumented!
            comp.LengthcmDropDown.Items = cm_arr;
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(comp, event)
            %global test_directory_name
            manufacturer = convertCharsToStrings(comp.ManufacturerEditField.Value);
            model = convertCharsToStrings(comp.ModelNameEditField.Value);
            year = convertCharsToStrings(comp.ModelYearEditField.Value);
            length = convertCharsToStrings(comp.LengthcmDropDown.Value);
            res = checkTestPlaceholderNameError(comp, "manufacturer name not inputted", convertCharsToStrings(comp.ManufacturerEditField.Placeholder), manufacturer);
            if ~res; return; end;
            res = checkTestPlaceholderNameError(comp, "model name not inputted", convertCharsToStrings(comp.ModelNameEditField.Placeholder), model);
            if ~res; return; end;
            res = checkTestPlaceholderNameError(comp, "model year was not inputted", convertCharsToStrings(comp.ModelYearEditField.Placeholder), year);
            if ~res; return; end;
            res = checkTestPlaceholderNameError(comp, "model length was not inputted", convertCharsToStrings(comp.LengthcmDropDown.Placeholder), length);
            if ~res; return; end;
            dir_name = strcat(manufacturer,"_",model,"_",year,"_",length);
            res = checkIfDirNameExists(comp, dir_name);
            if ~res; return; end;
            test_directory_name = dir_name;
            assignin('base','dir_name',test_directory_name);
            disp(dir_name);
       
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 385 627];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestDirectoryPanel = uipanel(comp);
            comp.TestDirectoryPanel.Title = 'Test Directory ';
            comp.TestDirectoryPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @TestDirectoryPanelButtonDown, true);
            comp.HasThisSkiAlreadyBeenTestedSwitch = uiswitch(comp.TestDirectoryPanel, 'slider');
            comp.HasThisSkiAlreadyBeenTestedSwitch.Items = {'Yes', 'No'};
            comp.HasThisSkiAlreadyBeenTestedSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @HasThisSkiAlreadyBeenTestedSwitchValueChanged, true);
            comp.HasThisSkiAlreadyBeenTestedSwitch.Value = 'Yes';
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel = uilabel(comp.TestDirectoryPanel);
            comp.HasThisSkiAlreadyBeenTestedSwitchLabel.Text = 'Has This Ski Already Been Tested';
            comp.SelectExistingTestNamePanel = uipanel(comp.TestDirectoryPanel);
            comp.SelectExistingTestNamePanel.Title = 'Select Existing Test Name';
            comp.ExistingTestNamesDropDownLabel = uilabel(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDownLabel.Text = 'Existing Test Names';
            comp.ExistingTestNamesDropDown = uidropdown(comp.SelectExistingTestNamePanel);
            comp.ExistingTestNamesDropDown.Items = {'Options'};
            comp.ExistingTestNamesDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @ExistingTestNamesDropDownOpening, true);
            comp.ExistingTestNamesDropDown.Placeholder = 'Options';
            comp.ExistingTestNamesDropDown.Value = 'Options';
            comp.SubmitButton_3 = uibutton(comp.SelectExistingTestNamePanel, 'push');
            comp.SubmitButton_3.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_3Pushed, true);
            comp.SubmitButton_3.Text = 'Submit';
            comp.NameTestPanel = uipanel(comp.TestDirectoryPanel);
            comp.NameTestPanel.Title = 'Name Test';
            comp.NameTestPanel.ButtonDownFcn = matlab.apps.createCallbackFcn(comp, @NameTestPanelButtonDown, true);
            comp.CustomNameSwitchLabel = uilabel(comp.NameTestPanel);
            comp.CustomNameSwitchLabel.Text = 'Custom Name';
            comp.CustomNameSwitch = uiswitch(comp.NameTestPanel, 'slider');
            comp.CustomNameSwitch.Items = {'Yes', 'No'};
            comp.CustomNameSwitch.ValueChangedFcn = matlab.apps.createCallbackFcn(comp, @CustomNameSwitchValueChanged, true);
            comp.CustomNameSwitch.Value = 'Yes';
            comp.CustomNamePanel = uipanel(comp.NameTestPanel);
            comp.CustomNamePanel.Title = 'Custom Name';
            comp.NameEditFieldLabel = uilabel(comp.CustomNamePanel);
            comp.NameEditFieldLabel.Text = 'Name';
            comp.NameEditField = uieditfield(comp.CustomNamePanel, 'text');
            comp.NameEditField.Placeholder = 'Enter Name';
            comp.SubmitButton = uibutton(comp.CustomNamePanel, 'push');
            comp.SubmitButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButtonPushed, true);
            comp.SubmitButton.Text = 'Submit';
            comp.StandardNamingPanel = uipanel(comp.NameTestPanel);
            comp.StandardNamingPanel.Title = 'Standard Naming';
            comp.ManufacturerEditFieldLabel = uilabel(comp.StandardNamingPanel);
            comp.ManufacturerEditFieldLabel.Text = 'Manufacturer';
            comp.ManufacturerEditField = uieditfield(comp.StandardNamingPanel, 'text');
            comp.ManufacturerEditField.Placeholder = 'Enter Manufacturer';
            comp.ModelNameEditFieldLabel = uilabel(comp.StandardNamingPanel);
            comp.ModelNameEditFieldLabel.Text = 'Model Name';
            comp.ModelNameEditField = uieditfield(comp.StandardNamingPanel, 'text');
            comp.ModelNameEditField.Placeholder = 'Enter Model Name';
            comp.ModelYearEditFieldLabel = uilabel(comp.StandardNamingPanel);
            comp.ModelYearEditFieldLabel.Text = 'Model Year';
            comp.ModelYearEditField = uieditfield(comp.StandardNamingPanel, 'text');
            comp.ModelYearEditField.Placeholder = 'Enter Model Year';
            comp.SubmitButton_2 = uibutton(comp.StandardNamingPanel, 'push');
            comp.SubmitButton_2.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SubmitButton_2Pushed, true);
            comp.SubmitButton_2.Text = 'Submit';
            comp.LengthcmDropDownLabel = uilabel(comp.StandardNamingPanel);
            comp.LengthcmDropDownLabel.Text = 'Length (cm)';
            comp.LengthcmDropDown = uidropdown(comp.StandardNamingPanel);
            comp.LengthcmDropDown.Items = {'Options'};
            comp.LengthcmDropDown.DropDownOpeningFcn = matlab.apps.createCallbackFcn(comp, @LengthcmDropDownOpening, true);
            comp.LengthcmDropDown.Placeholder = 'Options';
            comp.LengthcmDropDown.Value = 'Options';
        end
    end
end