classdef TestingControllerComp < matlab.ui.componentcontainer.ComponentContainer

    % Properties that correspond to underlying components
    properties (Access = private, Transient, NonCopyable)
        TestingPanel                  matlab.ui.container.Panel
        SaveTestButton                matlab.ui.control.Button
        RunSelectedTestButton         matlab.ui.control.Button
        SelectTesttoRunDropDown       matlab.ui.control.DropDown
        SelectTesttoRunDropDownLabel  matlab.ui.control.Label
        GJTestAxis                    matlab.ui.control.UIAxes
        EITestAxis                    matlab.ui.control.UIAxes
    end

    
    methods (Access = public)
        
        function results = run_unloaded_test(comp)
            %directory_name = evalin('base', 'directory_name');
            %arduino_serial = evalin('base', 'arduino_serial');
            %inclinometer_sreail = evalin('base', 'directory_name');
            %middle_inclinometer_sreial = evalin('base', 'directory_name');

            %if force guages are not both 0,
                %then move till zeroed
            %if middle inclinmeter is not level
                %then run level foce gauges
            %if ski inclinomter is not at start
                %then move to start

            data_matrix_unloaded = sensor_automation(test_interval_mm, direction);
            pause(2);     
            temp_save_single_test(data_matrix_unloaded, "data_matrix_unloaded");
            %assignin('base','unloaded',data_matrix_unloaded)
            test_distance_mm = size(data_matrix_unloaded,1)*test_interval_mm;
            dist_between_mm = 863; %change when distance between inclinomters change
            
            temp_save_single_test(data_matrix_unloaded, "Unloaded");
            sig = return_to_start(ardiuno_serial);
        end

        function result = run_loaded_test(comp)
            %directory_name = evalin('base', 'directory_name');
            %arduino_serial = evalin('base', 'arduino_serial');
            %inclinometer_sreail = evalin('base', 'directory_name');
            %middle_inclinometer_sreial = evalin('base', 'directory_name');

            %if inclinometer is not at start
                %sig = return_to_start(ardiuno_serial);
            %if force gauges are not above 30 lbs
              %sig = move_force_gauges(ardiuno_serial, 80, 80);
            % pause(1);
            %if force gauges are not level
                % %%%% HAVE TO MOVE THE INCLINOMETER TO THE MIDDLE TO LEVEL %%%
                % sig = level_force_gauges(ardiuno_serial, 0, .05, inclinometer_serial, 1);
                % %%% HAVE TO MOVE THE INCLINOMETER TO THE START TO START THE LOADED TEST %%%
            
            
            data_matrix_loaded = sensor_automation(test_interval_mm, direction);
            pause(2);
            
            temp_save_single_test(data_matrix_loaded, "data_matrix_loaded");
            
            %evalin(unloaded)
            [unloaded, loaded] = check_and_adjust_size(data_matrix_unloaded, data_matrix_loaded);%, truncate_from_bottom);
            % asignin(loaded)

            temp_save_single_test(loaded, "Loaded");
            [ei_x_points, ei_y_points] = get_EI_points(unloaded, loaded, test_interval_mm);
            temp_save_plot_and_points(ei_x_points, ei_y_points, 'EI');
            
            sig = return_to_start(ardiuno_serial);
        end
        
        function result = run_torsion_test(comp)
            %directory_name = evalin('base', 'directory_name');
            %arduino_serial = evalin('base', 'arduino_serial');
            %inclinometer_sreail = evalin('base', 'directory_name');
            %middle_inclinometer_sreial = evalin('base', 'directory_name');
            
            %if inclinometer not at start
                %sig = return_to_start(ardiuno_serial);
            %if force gauge not above 30
                %move up above 30
            %if force middle inclinometer level
                %sig = move_force_gauges(ardiuno_serial, 0, -20);
            
            data_matrix_torsion = sensor_automation(test_interval_mm, direction);
            pause(2);
            %evalin(unloaded);
            temp_save_single_test(data_matrix_torsion, "data_matrix_torsion");
            
            [unloaded, torsion] = check_and_adjust_size(data_matrix_unloaded, data_matrix_torsion);
            %assignin(torsion)
                       
            temp_save_single_test(torsion, "Torsion");

            [gj_x_points, gj_y_points] = get_GJ_points(unloaded, torsion, test_interval_mm);
            temp_save_plot_and_points(gj_x_points, gj_y_points, 'GJ');
            
            % move the force gauges to to 0 lbs
            sig = return_to_start(ardiuno_serial);
        end
        
        function result = run_full_test(comp)
            run_unloaded_test(comp);
            run_loaded_test(comp);
            run_torsion_test(comp);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Callback function: not associated with a component
        function SelectTesttoRunDropDownValueChanged(comp, event)
            value = comp.SelectTesttoRunDropDown.Value;
            
        end

        % Button pushed function: RunSelectedTestButton
        function RunSelectedTestButtonPushed(comp, event)
            selected_test = convertCharsToStrings(comp.SelectTesttoRunDropDown.Value);
            switch selected_test
                case "Unloaded Test"
                    run_unloaded_test(comp);
                case "Loaded Test"
                    run_loaded_test(comp);
                case "Torsion Test"
                    run_torsion_test(comp);
                case "Full Test"
                    run_full_test(comp);
                otherwise
                   str = "Test not selected. Please select test.";
                   fig = uifigure;
                   uialert(fig,str,'Test Error');
            end
        end

        % Button pushed function: SaveTestButton
        function SaveTestButtonPushed(comp, event)
            %directory_name = evalin('base', 'directory_name');
            
            save_data_clear_temp(directory_name);
            %if force gauges not at 0
                %then move to zero
                %move_force_gauges_until_desired_force(ardiuno_serial, force_gage1_serial, force_gage2_serial, 0, 5);
            %if inclinometer is not at start 
                %then move to start
        end
    end

    methods (Access = protected)
        
        % Code that executes when the value of a public property is changed
        function update(comp)
            % Use this function to update the underlying components
            
        end

        % Create the underlying components
        function setup(comp)
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            comp.Position = [1 1 385 581];
            comp.BackgroundColor = [0.94 0.94 0.94];
            comp.TestingPanel = uipanel(comp);
            comp.TestingPanel.Title = 'Testing Panel';
            comp.EITestAxis = uiaxes(comp.TestingPanel);
            title(comp.EITestAxis, 'EI Test')
            xlabel(comp.EITestAxis, 'Milimeters')
            ylabel(comp.EITestAxis, 'N*(m^2)')
            zlabel(comp.EITestAxis, 'Z')
            comp.GJTestAxis = uiaxes(comp.TestingPanel);
            title(comp.GJTestAxis, 'GJ Test')
            xlabel(comp.GJTestAxis, 'Milimeters')
            ylabel(comp.GJTestAxis, 'N*(m^2)')
            zlabel(comp.GJTestAxis, 'Z')
            comp.SelectTesttoRunDropDownLabel = uilabel(comp.TestingPanel);
            comp.SelectTesttoRunDropDownLabel.Text = 'Select Test to Run';
            comp.SelectTesttoRunDropDown = uidropdown(comp.TestingPanel);
            comp.SelectTesttoRunDropDown.Items = {'Options', 'Unloaded Test', 'Loaded Test', 'Torsion Test', 'Full Test'};
            comp.SelectTesttoRunDropDown.Placeholder = 'Options';
            comp.SelectTesttoRunDropDown.Value = 'Options';
            comp.RunSelectedTestButton = uibutton(comp.TestingPanel, 'push');
            comp.RunSelectedTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @RunSelectedTestButtonPushed, true);
            comp.RunSelectedTestButton.Text = 'Run Selected Test';
            comp.SaveTestButton = uibutton(comp.TestingPanel, 'push');
            comp.SaveTestButton.ButtonPushedFcn = matlab.apps.createCallbackFcn(comp, @SaveTestButtonPushed, true);
            comp.SaveTestButton.Text = 'Save Test';
        end
    end
end